classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        GiiThiuTab                   matlab.ui.container.Tab
        EditField_8                  matlab.ui.control.EditField
        EditField_8Label             matlab.ui.control.Label
        EditField_7                  matlab.ui.control.EditField
        EditField_7Label             matlab.ui.control.Label
        EditField_6                  matlab.ui.control.EditField
        EditField_6Label             matlab.ui.control.Label
        NhmEditField                 matlab.ui.control.EditField
        NhmEditFieldLabel            matlab.ui.control.Label
        EditField_5                  matlab.ui.control.EditField
        EditField_4                  matlab.ui.control.EditField
        EditField_3                  matlab.ui.control.EditField
        EditField_2                  matlab.ui.control.EditField
        EditField                    matlab.ui.control.EditField
        HiQuyTab                     matlab.ui.container.Tab
        note                         matlab.ui.control.EditField
        NoteLabel                    matlab.ui.control.Label
        EditField_10                 matlab.ui.control.EditField
        rv                           matlab.ui.control.EditField
        pr                           matlab.ui.control.EditField
        TnhButton                    matlab.ui.control.Button
        predict                      matlab.ui.control.EditField
        NhpgitrcndonxEditFieldLabel  matlab.ui.control.Label
        rm                           matlab.ui.control.EditField
        tinh                         matlab.ui.control.Button
        sm                           matlab.ui.control.DropDown
        ChnDropDownLabel             matlab.ui.control.Label
        EditField_9                  matlab.ui.control.EditField
        xdata                        matlab.ui.control.EditField
        NhpdliuxLabel                matlab.ui.control.Label
        ydata                        matlab.ui.control.EditField
        NhpdliuyEditFieldLabel       matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
        oHmTab                       matlab.ui.container.Tab
        NhapHamSo4                   matlab.ui.control.EditField
        NhpHmSLabel                  matlab.ui.control.Label
        GiaTriDH                     matlab.ui.control.NumericEditField
        GitrcntnhohmEditFieldLabel   matlab.ui.control.Label
        ChuoiY4                      matlab.ui.control.EditField
        ChuiyLabel                   matlab.ui.control.Label
        ChuoiX4                      matlab.ui.control.EditField
        ChuixLabel                   matlab.ui.control.Label
        ChonCachNhap_3               matlab.ui.control.DropDown
        ChncchNhpLabel_3             matlab.ui.control.Label
        ChonCachNhap_2               matlab.ui.control.DropDown
        ChncchNhpLabel_2             matlab.ui.control.Label
        PPNhapDH                     matlab.ui.control.DropDown
        ChncchNhpLabel               matlab.ui.control.Label
        BuocH                        matlab.ui.control.NumericEditField
        BcnhyLabel                   matlab.ui.control.Label
        KQ4                          matlab.ui.control.EditField
        TinhDaoHam                   matlab.ui.control.Button
        TchPhnTab                    matlab.ui.container.Tab
        BuocN                        matlab.ui.control.NumericEditField
        SonconNLabel                 matlab.ui.control.Label
        CanTPA                       matlab.ui.control.NumericEditField
        CnaEditFieldLabel            matlab.ui.control.Label
        CanTPB                       matlab.ui.control.NumericEditField
        CnbEditFieldLabel            matlab.ui.control.Label
        ChonPhuongPhapTP             matlab.ui.control.DropDown
        ChnphngphpDropDownLabel      matlab.ui.control.Label
        NhapHamSo5                   matlab.ui.control.EditField
        HmsLabel                     matlab.ui.control.Label
        ChuoiY5                      matlab.ui.control.EditField
        ChuiyLabel_2                 matlab.ui.control.Label
        ChuoiX5                      matlab.ui.control.EditField
        ChuixLabel_2                 matlab.ui.control.Label
        PhuongPhapNhapTP             matlab.ui.control.DropDown
        ChncchnhpDropDownLabel       matlab.ui.control.Label
        KQ5                          matlab.ui.control.EditField
        NhpkhongtnhtchphnLabel       matlab.ui.control.Label
        TnhtchphnButton              matlab.ui.control.Button
        TmnghimTab                   matlab.ui.container.Tab
        ham                          matlab.ui.control.EditField
        NhpphngtrnhLabel             matlab.ui.control.Label
        phuongphap                   matlab.ui.control.DropDown
        ChnphngphptmDropDownLabel    matlab.ui.control.Label
        ketqua                       matlab.ui.control.TextArea
        KtquathcnisuyLabel           matlab.ui.control.Label
        b                            matlab.ui.control.NumericEditField
        KhongphnlyLabel_2            matlab.ui.control.Label
        a                            matlab.ui.control.NumericEditField
        KhongphnlyLabel              matlab.ui.control.Label
        saiso                        matlab.ui.control.NumericEditField
        NhpgitrnisuyLabel            matlab.ui.control.Label
        title1                       matlab.ui.control.Label
        buttonNGHIEM                 matlab.ui.control.Button
        bieudo                       matlab.ui.control.UIAxes
        NiSuyTab                     matlab.ui.container.Tab
        y                            matlab.ui.control.TextArea
        KtquathcnisuyLabel_5         matlab.ui.control.Label
        x                            matlab.ui.control.TextArea
        KtquathcnisuyLabel_4         matlab.ui.control.Label
        ketquanoisuy                 matlab.ui.control.TextArea
        KtquathcnisuyLabel_3         matlab.ui.control.Label
        phuongphapnoisuy             matlab.ui.control.DropDown
        ChnphngphptmDropDownLabel_2  matlab.ui.control.Label
        ketquadathucnoisuy           matlab.ui.control.TextArea
        KtquathcnisuyLabel_2         matlab.ui.control.Label
        giatricannoisuy              matlab.ui.control.NumericEditField
        NhpgitrnisuyLabel_2          matlab.ui.control.Label
        title1_2                     matlab.ui.control.Label
        buttonNOISUY                 matlab.ui.control.Button
        bieudo_2                     matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: tinh
        function tinhButtonPushed(app, event)
            x = str2num(app.xdata.Value);
            y = str2num(app.ydata.Value);
            c = app.sm.Value;

            if (isempty(x) || isempty(y))
                app.note.Value = sprintf('Chuỗi x hoặc y không hợp lệ hoặc rỗng');
                return;
            end
            if(length(x) ~= length(y))
                app.note.Value = sprintf('Độ dài chuỗi x và y khác nhau');
                return;
            else
                app.note.Value = sprintf('Các thông số hợp lệ');
            end
            if(c == "Logarit")
                if(any(x <= 0) || any(y <= 0))
                    app.note.Value = sprintf('Mọi giá trị x và y phải lớn hơn 0 - Logarit');
                    return;
                else
                    app.note.Value = sprintf('Các thông số hợp lệ');
                end
            end
            if(c == "Hàm mũ" && any(y <= 0))
                app.note.Value = sprintf('Mọi giá trị y phải lớn hơn 0 - Hàm mũ');
                return;
            else
                app.note.Value = sprintf('Các thông số hợp lệ');
            end


            if(c == "Tuyến tính")
                [a0, a1, r2] = HoiQuy(x, y);
                app.rm.Value = sprintf('%.3f + %.3f*x', a1, a0);
                app.rv.Value = sprintf('r2 = %.6f', r2);
                yhq = a1 + a0.*x;
            end

            if(c == "Logarit")
                X = log10(x);
                Y = log10(y);
                [a0, a1, r2] = HoiQuy(X, Y);
                a = 10^a1;
                b = a0;
                app.rm.Value = sprintf('%.3f*x.^%.3f', a, b);
                app.rv.Value = sprintf('r2 = %.6f', r2);
                yhq = a*x.^b;
            end

            if(c == "Hàm mũ")
                X = x;
                Y = log(y);
                [a0, a1, r2] = HoiQuy(X, Y);
                a = exp(a1);
                b = a0;
                app.rm.Value = sprintf('%.3f*exp(%.3f*x)', a, b); 
                app.rv.Value = sprintf('r2 = %.6f', r2);
                yhq = a*exp(b.*x);
            end
            
            plot(app.UIAxes, x, y, 'ro', 'MarkerSize', 10, 'LineWidth', 1.5, 'DisplayName', 'Giá trị thực');
            hold(app.UIAxes, 'on'); 
            plot(app.UIAxes, x, yhq, 'b-', 'LineWidth', 2, 'DisplayName', 'Đường hồi quy'); 
            legend(app.UIAxes);
            hold(app.UIAxes, 'off');
        end

        % Button pushed function: TnhButton
        function TnhButtonPushed(app, event)
            tinhButtonPushed(app, event);
            Fx = app.rm.Value;
            x  = str2num(app.predict.Value);
            
            if (isempty(x)) 
                app.note.Value = sprintf('Chưa nhập giá trị cần dự đoán');
            end

            fx = str2func(['@(x)', Fx]); 
            rp = fx(x);
            y  = sprintf('%.4f ', rp);
            app.pr.Value = y;
        end

        % Button pushed function: TinhDaoHam
        function TinhDaoHamButtonPushed(app, event)
            ChonCachNhap = app.PPNhapDH.Value;
            xa4 = str2num(app.ChuoiX4.Value);
            ya4 = str2num(app.ChuoiY4.Value);
            ss4 = app.ChonCachNhap_2.Value;
            pp4 = app.ChonCachNhap_3.Value;
            
            if(ChonCachNhap == "Nhập chuỗi")
                giatri4 = app.GiaTriDH.Value;
                dx = DaoHamChuoi(xa4,ya4,giatri4,pp4,ss4);
                app.KQ4.Value = num2str(dx);
            elseif(ChonCachNhap == "Nhập hàm số")
                f4 = app.NhapHamSo4.Value;
                f4 = str2func(['@(x)',f4]);
                h = app.BuocH.Value;
                x4 = app.GiaTriDH.Value;
                dx = DaoHamHamSo(f4,h,x4,pp4,ss4);
                app.KQ4.Value = num2str(dx);
            end
        end

        % Value changed function: PPNhapDH
        function PPNhapDHValueChanged(app, event)
            ChonCachNhap = app.PPNhapDH.Value;
            if(ChonCachNhap == "Nhập chuỗi")
                app.BuocH.Visible = 'off';
                app.NhapHamSo4.Visible = 'off';
                app.ChuoiX4.Visible = 'on';
                app.ChuoiY4.Visible = 'on';
            elseif(ChonCachNhap == "Nhập hàm số")
                app.BuocH.Visible = 'on';
                app.NhapHamSo4.Visible = 'on';
                app.ChuoiX4.Visible = 'off';
                app.ChuoiY4.Visible = 'off';
            end
        end

        % Value changed function: PhuongPhapNhapTP
        function PhuongPhapNhapTPValueChanged(app, event)
            ChonCachNhap2 = app.PhuongPhapNhapTP.Value;
            if(ChonCachNhap2 == "Nhập chuỗi")
                app.NhapHamSo5.Visible = "off";
                app.ChuoiY5.Visible = "on";
                app.ChuoiX5.Visible = "on";
            elseif(ChonCachNhap2 == "Nhập hàm số")
                app.NhapHamSo5.Visible = "on";
                app.ChuoiY5.Visible = "off";
                app.ChuoiX5.Visible = "off";
            end        
        end

        % Button pushed function: TnhtchphnButton
        function TnhtchphnButtonPushed(app, event)
            ChonPPTP = app.ChonPhuongPhapTP.Value;
            chonCachNhapTP = app.PhuongPhapNhapTP.Value;
            if chonCachNhapTP == "Nhập hàm số"
            a = app.CanTPA.Value; 
            b = app.CanTPB.Value;
            N = app.BuocN.Value;
            f5 = app.NhapHamSo5.Value;
            f5 = str2func(['@(x)',f5]);
            y = PhuongPhapTichPhan(f5,a,b,N,ChonPPTP);
            app.KQ5.Value = num2str(y);
            elseif chonCachNhapTP == "Nhập chuỗi"
            xa5 = str2num(app.ChuoiX5.Value);
            ya5 = str2num(app.ChuoiY5.Value);
            y5 = HamChuyenChuoi(xa5,ya5);
            a = app.CanTPA.Value; 
            b = app.CanTPB.Value;
            N = app.BuocN.Value;
            y = PhuongPhapTichPhan(y5,a,b,N,ChonPPTP);
            app.KQ5.Value = num2str(y);
            end            
        end

        % Button pushed function: buttonNGHIEM
        function buttonNGHIEMButtonPushed(app, event)
a = app.a.Value;
b = app.b.Value;
epsilon = app.saiso.Value;  % Sai số nhập vào

% Kiểm tra xem các giá trị có hợp lệ không (không phải NaN)
if isnan(a) || isnan(b) || isnan(epsilon)
    app.ketqua.Value = 'Vui lòng nhập số hợp lệ cho các trường a, b và sai số.';
    return;
end

% Lấy hàm từ Edit Field Text
f_str = app.ham.Value;

% Kiểm tra nếu chuỗi f_str trống
if isempty(f_str)
    app.ketqua.Value = 'Vui lòng nhập một hàm hợp lệ.';
    return;
end

try
    % Chuyển chuỗi hàm nhập vào thành hàm số trong MATLAB (anonymous function)
    f_func = str2func(['@(x) ' f_str]);  % Biểu thức hàm như một anonymous function
    
    % Tính đạo hàm (xấp xỉ bằng sai phân hữu hạn)
    df_func = @(x) (f_func(x + epsilon) - f_func(x)) / epsilon;  % Đạo hàm xấp xỉ
catch err
    app.ketqua.Value = ['Hàm không hợp lệ. Lỗi: ' err.message];
    return;
end

% Số lần lặp tối đa
max_iter = 100;

% Tìm nghiệm bằng các phương pháp
results = struct();

% Phương pháp Chia đôi
try
    [x_iter, iter] = phuongphapChiaDoi(f_func, a, b, epsilon, max_iter);
    results.phuongphapChiaDoi = struct('Root', x_iter, 'Iterations', iter);
catch
    results.phuongphapChiaDoi = struct('Root', NaN, 'Iterations', 0);
end

% Phương pháp Lặp đơn
g = @(x) x - f_func(x) / max(abs(df_func(x)), 1e-6); % Định nghĩa g(x)
try
    % Điểm bắt đầu là trung điểm của đoạn [a, b]
    x0 = (a + b) / 2;

    % Tìm nghiệm bằng phương pháp lặp đơn
    [x_iter, iter] = phuongphapLapDon(g, x0, epsilon, max_iter);
    results.phuongphapLapDon = struct('Root', x_iter, 'Iterations', iter);
catch err
    % Xử lý lỗi nếu không hội tụ
    results.phuongphapLapDon = struct('Root', NaN, 'Iterations', 0);
    app.ketqua.Value = ['Phương pháp Lặp đơn gặp lỗi: ', err.message];
    return;
end

% Phương pháp Tiếp tuyến
try
    [x_iter, iter] = phuongphapTiepTuyen(f_func, df_func, a, epsilon, max_iter);
    results.phuongphapTiepTuyen = struct('Root', x_iter, 'Iterations', iter);
catch
    results.phuongphapTiepTuyen = struct('Root', NaN, 'Iterations', 0);
end

% Lựa chọn phương pháp từ Dropdown
selectedMethod = app.phuongphap.Value;
switch selectedMethod
    case 'Chia đôi'
        resultText = sprintf('Phương pháp Chia đôi:\n  Nghiệm: %.6f\n  Số lần lặp: %d', ...
            results.phuongphapChiaDoi.Root, results.phuongphapChiaDoi.Iterations);
    case 'Lặp đơn'
        resultText = sprintf('Phương pháp Lặp đơn:\n  Nghiệm: %.6f\n  Số lần lặp: %d', ...
            results.phuongphapLapDon.Root, results.phuongphapLapDon.Iterations);
    case 'Tiếp tuyến'
        resultText = sprintf('Phương pháp Tiếp tuyến:\n  Nghiệm: %.6f\n  Số lần lặp: %d', ...
            results.phuongphapTiepTuyen.Root, results.phuongphapTiepTuyen.Iterations);
end

% Hiển thị kết quả
app.ketqua.Value = resultText;

% Vẽ đồ thị
cla(app.bieudo); % Xóa nội dung cũ trên biểu đồ
f = fplot(app.bieudo, f_func, [a, b], 'LineWidth', 2, 'Color', 'b', ...
    'DisplayName', 'f(x)'); % Vẽ đồ thị của hàm f(x) và gán nhãn 'f(x)'
hold(app.bieudo, 'on');

% Làm nổi bật nghiệm gần đúng
if strcmp(selectedMethod, 'Chia đôi')
    plot(app.bieudo, results.phuongphapChiaDoi.Root, 0, 'mo', 'MarkerSize', 10, ...
        'DisplayName', sprintf('Chia đôi: %.6f', results.phuongphapChiaDoi.Root));
elseif strcmp(selectedMethod, 'Lặp đơn')
    plot(app.bieudo, results.phuongphapLapDon.Root, 0, 'ro', 'MarkerSize', 10, ...
        'DisplayName', sprintf('Lặp đơn: %.6f', results.phuongphapLapDon.Root));
elseif strcmp(selectedMethod, 'Tiếp tuyến')
    plot(app.bieudo, results.phuongphapTiepTuyen.Root, 0, 'bo', 'MarkerSize', 10, ...
        'DisplayName', sprintf('Tiếp tuyến: %.6f', results.phuongphapTiepTuyen.Root));
end
% Tùy chỉnh biểu đồ
legend(app.bieudo, 'show', 'Location', 'best');
xlabel(app.bieudo, 'x');
ylabel(app.bieudo, 'f(x)');
title(app.bieudo, 'Đồ thị hàm số và nghiệm gần đúng');
grid(app.bieudo, 'on');
hold(app.bieudo, 'off');

        end

        % Button pushed function: buttonNOISUY
        function buttonNOISUYButtonPushed(app, event)
    try
    % Đọc giá trị từ TextArea
    x_str = app.x.Value; % Giá trị x (chuỗi thô)
    y_str = app.y.Value; % Giá trị y (chuỗi thô)
    
    % Chuyển chuỗi thành mảng số
    x = str2double(split(x_str, ',')); % Phân tách theo dấu phẩy
    y = str2double(split(y_str, ',')); 
    
    % Đọc giá trị từ EditField Numeric
    x0 = app.giatricannoisuy.Value; % Giá trị x0 (số thực)
catch ME
    app.ketquadathucnoisuy.Value = {'Lỗi: Không lấy được dữ liệu từ giao diện'};
    disp(ME.message);
    return;
end

% Kiểm tra dữ liệu đầu vào
if isempty(x) || isempty(y)
    app.ketquadathucnoisuy.Value = {'Lỗi: x hoặc y không có giá trị hợp lệ'};
    return;
end
if any(isnan(x)) || any(isnan(y)) || isnan(x0)
    app.ketquadathucnoisuy.Value = {'Lỗi: Dữ liệu đầu vào chứa giá trị không hợp lệ (NaN)'};
    return;
end
if length(x) ~= length(y)
    app.ketquadathucnoisuy.Value = {'Lỗi: x và y phải có cùng số phần tử'};
    return;
end
if x0 < min(x) || x0 > max(x)
    app.ketquadathucnoisuy.Value = {sprintf('Lỗi: x0 (%.2f) nằm ngoài phạm vi [%.2f, %.2f] của x', x0, min(x), max(x))};
    return;
end

% Thực hiện nội suy
try
    phuongphapnoisuy = strtrim(char(app.phuongphapnoisuy.Value));
    switch phuongphapnoisuy
        case 'Lagrange'
            [ketquanoisuy, ketquadathucnoisuy] = noisuyLagrange(x, y, x0);
        case 'Newton'
            [ketquanoisuy, ketquadathucnoisuy] = noisuyNewton(x, y, x0);
        otherwise
            app.ketquadathucnoisuy.Value = {'Lỗi: Phương pháp nội suy không hợp lệ'};
            return;
    end
catch ME
    disp('Lỗi khi thực hiện nội suy:');
    disp(ME.message);
    app.ketquadathucnoisuy.Value = {'Lỗi: Không thể thực hiện nội suy'};
    return;
end

% Hiển thị kết quả
if isnan(ketquanoisuy)
    app.ketquadathucnoisuy.Value = {'Lỗi: Kết quả nội suy không hợp lệ'};
    return;
end

app.ketquanoisuy.Value = {num2str(ketquanoisuy)};
app.ketquadathucnoisuy.Value = {sprintf('P(x0) = %.4f', ketquanoisuy)};
app.ketquadathucnoisuy.Value = {sprintf('Đa thức nội suy: %s', ketquadathucnoisuy)};

% Vẽ đồ thị
try
    vehamnoisuy(x, y, ketquanoisuy, x0, app.bieudo_2, phuongphapnoisuy);
catch ME
    disp('Lỗi khi vẽ đồ thị:');
    disp(ME.message);
    app.ketquadathucnoisuy.Value = {'Lỗi: Không thể vẽ đồ thị'};
end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GiiThiuTab = uitab(app.TabGroup);
            app.GiiThiuTab.Title = 'Giới Thiệu';
            app.EditField = uieditfield(app.GiiThiuTab, 'text');
            app.EditField.Value = 'Thành viên:';
            app.EditField_2 = uieditfield(app.GiiThiuTab, 'text');
            app.EditField_2.Value = 'Nhiệm vụ:';
            app.EditField_3 = uieditfield(app.GiiThiuTab, 'text');
            app.EditField_3.Value = 'Thiết kế tab Đạo hàm và Tích phân';
            app.EditField_4 = uieditfield(app.GiiThiuTab, 'text');
            app.EditField_4.Value = 'Thiết kế tab Tìm nghiệm và Nội suy';
            app.EditField_5 = uieditfield(app.GiiThiuTab, 'text');
            app.EditField_5.Value = 'Thiết kế tab Hồi quy và Giới thiệu - Tổng hợp';
            app.NhmEditFieldLabel = uilabel(app.GiiThiuTab);
            app.NhmEditFieldLabel.Text = 'Nhóm:';
            app.NhmEditField = uieditfield(app.GiiThiuTab, 'text');
            app.NhmEditField.Value = '3 Brothers';
            app.EditField_6Label = uilabel(app.GiiThiuTab);
            app.EditField_6Label.Text = '1.';
            app.EditField_6 = uieditfield(app.GiiThiuTab, 'text');
            app.EditField_6.Value = 'Phạm Nguyễn Cao Triều - 22207123';
            app.EditField_7Label = uilabel(app.GiiThiuTab);
            app.EditField_7Label.Text = '2.';
            app.EditField_7 = uieditfield(app.GiiThiuTab, 'text');
            app.EditField_7.Value = 'Trương Quang Hưng - 22207103';
            app.EditField_8Label = uilabel(app.GiiThiuTab);
            app.EditField_8Label.Text = '3.';
            app.EditField_8 = uieditfield(app.GiiThiuTab, 'text');
            app.EditField_8.Value = 'Huỳnh Phạm Minh Tú - 22207094';
            app.HiQuyTab = uitab(app.TabGroup);
            app.HiQuyTab.Title = 'Hồi Quy';
            app.UIAxes = uiaxes(app.HiQuyTab);
            title(app.UIAxes, 'Đường hồi quy với giá trị thực')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.NhpdliuyEditFieldLabel = uilabel(app.HiQuyTab);
            app.NhpdliuyEditFieldLabel.Text = 'Nhập dữ liệu y:';
            app.ydata = uieditfield(app.HiQuyTab, 'text');
            app.ydata.Value = '5.2 7.8 10.7 13 19.3 27.5';
            app.NhpdliuxLabel = uilabel(app.HiQuyTab);
            app.NhpdliuxLabel.Text = 'Nhập dữ liệu x:';
            app.xdata = uieditfield(app.HiQuyTab, 'text');
            app.xdata.Value = '2, 3, 4, 5, 7, 10';
            app.EditField_9 = uieditfield(app.HiQuyTab, 'text');
            app.EditField_9.Value = 'Chọn phương pháp hồi quy:';
            app.ChnDropDownLabel = uilabel(app.HiQuyTab);
            app.ChnDropDownLabel.Text = 'Chọn';
            app.sm = uidropdown(app.HiQuyTab);
            app.sm.Items = {'Tuyến tính', 'Hàm mũ', 'Logarit'};
            app.sm.Value = 'Tuyến tính';
            app.tinh = uibutton(app.HiQuyTab, 'push');
            app.tinh.ButtonPushedFcn = createCallbackFcn(app, @tinhButtonPushed, true);
            app.tinh.Text = 'Tính';
            app.rm = uieditfield(app.HiQuyTab, 'text');
            app.rm.Editable = 'off';
            app.NhpgitrcndonxEditFieldLabel = uilabel(app.HiQuyTab);
            app.NhpgitrcndonxEditFieldLabel.Text = 'Nhập giá trị cần dự đoán x =';
            app.predict = uieditfield(app.HiQuyTab, 'text');
            app.predict.Placeholder = 'Nhập 1 hoặc 1 mảng giá trị';
            app.TnhButton = uibutton(app.HiQuyTab, 'push');
            app.TnhButton.ButtonPushedFcn = createCallbackFcn(app, @TnhButtonPushed, true);
            app.TnhButton.Text = 'Tính';
            app.pr = uieditfield(app.HiQuyTab, 'text');
            app.pr.Editable = 'off';
            app.rv = uieditfield(app.HiQuyTab, 'text');
            app.rv.Editable = 'off';
            app.EditField_10 = uieditfield(app.HiQuyTab, 'text');
            app.EditField_10.Value = 'y = ';
            app.NoteLabel = uilabel(app.HiQuyTab);
            app.NoteLabel.Text = 'Note:';
            app.note = uieditfield(app.HiQuyTab, 'text');
            app.note.Editable = 'off';
            app.oHmTab = uitab(app.TabGroup);
            app.oHmTab.Title = 'Đạo Hàm';
            app.TinhDaoHam = uibutton(app.oHmTab, 'push');
            app.TinhDaoHam.ButtonPushedFcn = createCallbackFcn(app, @TinhDaoHamButtonPushed, true);
            app.TinhDaoHam.Text = 'Tính đạo hàm';
            app.KQ4 = uieditfield(app.oHmTab, 'text');
            app.KQ4.Placeholder = 'Kết quả';
            app.BcnhyLabel = uilabel(app.oHmTab);
            app.BcnhyLabel.Text = 'Bước nhảy ';
            app.BuocH = uieditfield(app.oHmTab, 'numeric');
            app.BuocH.Visible = 'off';
            app.ChncchNhpLabel = uilabel(app.oHmTab);
            app.ChncchNhpLabel.Text = 'Chọn cách Nhập';
            app.PPNhapDH = uidropdown(app.oHmTab);
            app.PPNhapDH.Items = {'Nhập chuỗi', 'Nhập hàm số'};
            app.PPNhapDH.ValueChangedFcn = createCallbackFcn(app, @PPNhapDHValueChanged, true);
            app.PPNhapDH.Value = 'Nhập chuỗi';
            app.ChncchNhpLabel_2 = uilabel(app.oHmTab);
            app.ChncchNhpLabel_2.Text = 'Chọn sai số cắt cụt';
            app.ChonCachNhap_2 = uidropdown(app.oHmTab);
            app.ChonCachNhap_2.Items = {'O(h)', 'O(h^2)', 'O(h^4) - Chiều trung tâm'};
            app.ChonCachNhap_2.Value = 'O(h)';
            app.ChncchNhpLabel_3 = uilabel(app.oHmTab);
            app.ChncchNhpLabel_3.Text = 'Chọn phương pháp';
            app.ChonCachNhap_3 = uidropdown(app.oHmTab);
            app.ChonCachNhap_3.Items = {'Chiều tiến', 'Chiều lùi', 'Trung tâm'};
            app.ChonCachNhap_3.Value = 'Chiều tiến';
            app.ChuixLabel = uilabel(app.oHmTab);
            app.ChuixLabel.Text = 'Chuỗi x';
            app.ChuoiX4 = uieditfield(app.oHmTab, 'text');
            app.ChuoiX4.Placeholder = 'Nhập chuỗi x';
            app.ChuiyLabel = uilabel(app.oHmTab);
            app.ChuiyLabel.Text = 'Chuỗi y';
            app.ChuoiY4 = uieditfield(app.oHmTab, 'text');
            app.ChuoiY4.Placeholder = 'Nhập chuỗi y';
            app.GitrcntnhohmEditFieldLabel = uilabel(app.oHmTab);
            app.GitrcntnhohmEditFieldLabel.Text = 'Giá trị cần tính đạo hàm';
            app.GiaTriDH = uieditfield(app.oHmTab, 'numeric');
            app.NhpHmSLabel = uilabel(app.oHmTab);
            app.NhpHmSLabel.Text = 'Hàm số';
            app.NhapHamSo4 = uieditfield(app.oHmTab, 'text');
            app.NhapHamSo4.Visible = 'off';
            app.NhapHamSo4.Placeholder = 'Nhập hàm số';
            app.TchPhnTab = uitab(app.TabGroup);
            app.TchPhnTab.Title = 'Tích Phân';
            app.TnhtchphnButton = uibutton(app.TchPhnTab, 'push');
            app.TnhtchphnButton.ButtonPushedFcn = createCallbackFcn(app, @TnhtchphnButtonPushed, true);
            app.TnhtchphnButton.Text = 'Tính tích phân';
            app.NhpkhongtnhtchphnLabel = uilabel(app.TchPhnTab);
            app.NhpkhongtnhtchphnLabel.Text = 'Nhập khoảng tính tích phân';
            app.KQ5 = uieditfield(app.TchPhnTab, 'text');
            app.KQ5.Placeholder = 'Kết quả';
            app.ChncchnhpDropDownLabel = uilabel(app.TchPhnTab);
            app.ChncchnhpDropDownLabel.Text = 'Chọn cách nhập';
            app.PhuongPhapNhapTP = uidropdown(app.TchPhnTab);
            app.PhuongPhapNhapTP.Items = {'Nhập chuỗi', 'Nhập hàm số'};
            app.PhuongPhapNhapTP.ValueChangedFcn = createCallbackFcn(app, @PhuongPhapNhapTPValueChanged, true);
            app.PhuongPhapNhapTP.Value = 'Nhập chuỗi';
            app.ChuixLabel_2 = uilabel(app.TchPhnTab);
            app.ChuixLabel_2.Text = 'Chuỗi x';
            app.ChuoiX5 = uieditfield(app.TchPhnTab, 'text');
            app.ChuoiX5.Placeholder = 'Nhập dữ liệu x';
            app.ChuiyLabel_2 = uilabel(app.TchPhnTab);
            app.ChuiyLabel_2.Text = 'Chuỗi y';
            app.ChuoiY5 = uieditfield(app.TchPhnTab, 'text');
            app.ChuoiY5.Placeholder = 'Nhập dữ liệu y';
            app.HmsLabel = uilabel(app.TchPhnTab);
            app.HmsLabel.Text = 'Hàm số';
            app.NhapHamSo5 = uieditfield(app.TchPhnTab, 'text');
            app.NhapHamSo5.Visible = 'off';
            app.NhapHamSo5.Placeholder = 'Nhập hàm số';
            app.ChnphngphpDropDownLabel = uilabel(app.TchPhnTab);
            app.ChnphngphpDropDownLabel.Text = 'Chọn phương pháp';
            app.ChonPhuongPhapTP = uidropdown(app.TchPhnTab);
            app.ChonPhuongPhapTP.Items = {'Hình thang', 'Simpson 1/3', 'Simpson 3/8'};
            app.ChonPhuongPhapTP.Value = 'Hình thang';
            app.CnbEditFieldLabel = uilabel(app.TchPhnTab);
            app.CnbEditFieldLabel.Text = 'Cận b';
            app.CanTPB = uieditfield(app.TchPhnTab, 'numeric');
            app.CnaEditFieldLabel = uilabel(app.TchPhnTab);
            app.CnaEditFieldLabel.Text = 'Cận a';
            app.CanTPA = uieditfield(app.TchPhnTab, 'numeric');
            app.SonconNLabel = uilabel(app.TchPhnTab);
            app.SonconNLabel.Text = 'Số đoạn con N';
            app.BuocN = uieditfield(app.TchPhnTab, 'numeric');
            app.TmnghimTab = uitab(app.TabGroup);
            app.TmnghimTab.Title = 'Tìm nghiệm';
            app.bieudo = uiaxes(app.TmnghimTab);
            title(app.bieudo, 'Biểu đồ ')
            xlabel(app.bieudo, 'X')
            ylabel(app.bieudo, 'Y')
            zlabel(app.bieudo, 'Z')
            app.buttonNGHIEM = uibutton(app.TmnghimTab, 'push');
            app.buttonNGHIEM.ButtonPushedFcn = createCallbackFcn(app, @buttonNGHIEMButtonPushed, true);
            app.buttonNGHIEM.Text = 'Tính toán';
            app.title1 = uilabel(app.TmnghimTab);
            app.title1.Text = 'Giải gần đúng phương trình ';
            app.NhpgitrnisuyLabel = uilabel(app.TmnghimTab);
            app.NhpgitrnisuyLabel.Text = 'Nhập sai số';
            app.saiso = uieditfield(app.TmnghimTab, 'numeric');
            app.KhongphnlyLabel = uilabel(app.TmnghimTab);
            app.KhongphnlyLabel.Text = 'Biên trái ( Cận a ) ';
            app.a = uieditfield(app.TmnghimTab, 'numeric');
            app.KhongphnlyLabel_2 = uilabel(app.TmnghimTab);
            app.KhongphnlyLabel_2.Text = 'Biên phải ( Cận b )';
            app.b = uieditfield(app.TmnghimTab, 'numeric');
            app.KtquathcnisuyLabel = uilabel(app.TmnghimTab);
            app.KtquathcnisuyLabel.Text = 'Kết quả nghiệm ';
            app.ketqua = uitextarea(app.TmnghimTab);
            app.ChnphngphptmDropDownLabel = uilabel(app.TmnghimTab);
            app.ChnphngphptmDropDownLabel.Text = 'Chọn phương pháp tìm ';
            app.phuongphap = uidropdown(app.TmnghimTab);
            app.phuongphap.Items = {'Chia đôi', 'Lặp đơn', 'Tiếp tuyến'};
            app.phuongphap.Value = 'Chia đôi';
            app.NhpphngtrnhLabel = uilabel(app.TmnghimTab);
            app.NhpphngtrnhLabel.Text = 'Nhập phương trình : ';
            app.ham = uieditfield(app.TmnghimTab, 'text');
            app.NiSuyTab = uitab(app.TabGroup);
            app.NiSuyTab.Title = 'Nội Suy';
            app.bieudo_2 = uiaxes(app.NiSuyTab);
            title(app.bieudo_2, 'Biểu đồ ')
            xlabel(app.bieudo_2, 'X')
            ylabel(app.bieudo_2, 'Y')
            zlabel(app.bieudo_2, 'Z')
            app.buttonNOISUY = uibutton(app.NiSuyTab, 'push');
            app.buttonNOISUY.ButtonPushedFcn = createCallbackFcn(app, @buttonNOISUYButtonPushed, true);
            app.buttonNOISUY.Text = 'Tính toán';
            app.title1_2 = uilabel(app.NiSuyTab);
            app.title1_2.Text = 'Giải Nội suy ';
            app.NhpgitrnisuyLabel_2 = uilabel(app.NiSuyTab);
            app.NhpgitrnisuyLabel_2.Text = 'Nhập giá trị nội suy ';
            app.giatricannoisuy = uieditfield(app.NiSuyTab, 'numeric');
            app.KtquathcnisuyLabel_2 = uilabel(app.NiSuyTab);
            app.KtquathcnisuyLabel_2.Text = 'Kết quả đa thức nội suy ';
            app.ketquadathucnoisuy = uitextarea(app.NiSuyTab);
            app.ChnphngphptmDropDownLabel_2 = uilabel(app.NiSuyTab);
            app.ChnphngphptmDropDownLabel_2.Text = 'Chọn phương pháp tìm ';
            app.phuongphapnoisuy = uidropdown(app.NiSuyTab);
            app.phuongphapnoisuy.Items = {'Lagrange ', 'Newton'};
            app.phuongphapnoisuy.Value = 'Lagrange ';
            app.KtquathcnisuyLabel_3 = uilabel(app.NiSuyTab);
            app.KtquathcnisuyLabel_3.Text = 'Kết quả nội suy ';
            app.ketquanoisuy = uitextarea(app.NiSuyTab);
            app.KtquathcnisuyLabel_4 = uilabel(app.NiSuyTab);
            app.KtquathcnisuyLabel_4.Text = 'Nhập dữ liệu của x';
            app.x = uitextarea(app.NiSuyTab);
            app.KtquathcnisuyLabel_5 = uilabel(app.NiSuyTab);
            app.KtquathcnisuyLabel_5.Text = 'Nhập dữ liệu của y';
            app.y = uitextarea(app.NiSuyTab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end